classdef slicer_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        UIAxes                       matlab.ui.control.UIAxes
        UIAxes2                      matlab.ui.control.UIAxes
        ImportButton                 matlab.ui.control.Button
        FilePathEditFieldLabel       matlab.ui.control.Label
        FilePathEditField            matlab.ui.control.EditField
        ShowMeshButton               matlab.ui.control.Button
        TraceWidthEditFieldLabel     matlab.ui.control.Label
        TraceWidthEditField          matlab.ui.control.NumericEditField
        LayerHeightEditFieldLabel    matlab.ui.control.Label
        LayerHeightEditField         matlab.ui.control.NumericEditField
        BuildvolumeLabel             matlab.ui.control.Label
        xaxisEditFieldLabel          matlab.ui.control.Label
        xaxisEditField               matlab.ui.control.NumericEditField
        yaxisEditFieldLabel          matlab.ui.control.Label
        yaxisEditField               matlab.ui.control.NumericEditField
        zaxisEditFieldLabel          matlab.ui.control.Label
        zaxisEditField               matlab.ui.control.NumericEditField
        SliceButton                  matlab.ui.control.Button
        PerimetersEditFieldLabel     matlab.ui.control.Label
        PerimetersEditField          matlab.ui.control.NumericEditField
        mmLabel                      matlab.ui.control.Label
        mmLabel_2                    matlab.ui.control.Label
        TraceOverlapEditFieldLabel   matlab.ui.control.Label
        TraceOverlapEditField        matlab.ui.control.NumericEditField
        Label                        matlab.ui.control.Label
        MinimumTraceLengthEditFieldLabel  matlab.ui.control.Label
        MinimumTraceLengthEditField  matlab.ui.control.NumericEditField
        mmLabel_3                    matlab.ui.control.Label
        TracePatternDropDownLabel    matlab.ui.control.Label
        TracePatternDropDown         matlab.ui.control.DropDown
    end

    
    properties (Access = public)
        triangles % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            [file, path] = uigetfile('*.stl');
            full_file_path_name = strcat(path,file);
            %app.textfield_stl_filename.Text = full_file_path_name;
            app.FilePathEditField.Value = full_file_path_name;
        end

        % Button pushed function: ShowMeshButton
        function ShowMeshButtonPushed(app, event)
            filename = app.FilePathEditField.Value;
            app.triangles = read_binary_stl_file(filename);
            plot_stl(app.triangles,app.UIAxes);
        end

        % Button pushed function: SliceButton
        function SliceButtonPushed(app, event)
                    layer = app.LayerHeightEditField.Value;
            per = app.PerimetersEditField.Value;
            widthE = app.TraceWidthEditField.Value;
            overlap = app.TraceOverlapEditField.Value;
            main_final(app.triangles,layer,widthE*(1-(overlap/100)),per,app.UIAxes2);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'STL Mesh')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Trace Preview')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.ImportButton = uibutton(app.UIFigure, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.FilePathEditFieldLabel = uilabel(app.UIFigure);
            app.FilePathEditFieldLabel.Text = 'File Path';
            app.FilePathEditField = uieditfield(app.UIFigure, 'text');
            app.ShowMeshButton = uibutton(app.UIFigure, 'push');
            app.ShowMeshButton.ButtonPushedFcn = createCallbackFcn(app, @ShowMeshButtonPushed, true);
            app.ShowMeshButton.Text = 'Show Mesh';
            app.TraceWidthEditFieldLabel = uilabel(app.UIFigure);
            app.TraceWidthEditFieldLabel.Text = 'Trace Width';
            app.TraceWidthEditField = uieditfield(app.UIFigure, 'numeric');
            app.TraceWidthEditField.Value = 1;
            app.LayerHeightEditFieldLabel = uilabel(app.UIFigure);
            app.LayerHeightEditFieldLabel.Text = 'Layer Height';
            app.LayerHeightEditField = uieditfield(app.UIFigure, 'numeric');
            app.LayerHeightEditField.Value = 0.5;
            app.BuildvolumeLabel = uilabel(app.UIFigure);
            app.BuildvolumeLabel.Text = 'Build volume';
            app.xaxisEditFieldLabel = uilabel(app.UIFigure);
            app.xaxisEditFieldLabel.Text = 'x-axis';
            app.xaxisEditField = uieditfield(app.UIFigure, 'numeric');
            app.xaxisEditField.Value = 250;
            app.yaxisEditFieldLabel = uilabel(app.UIFigure);
            app.yaxisEditFieldLabel.Text = 'y-axis';
            app.yaxisEditField = uieditfield(app.UIFigure, 'numeric');
            app.yaxisEditField.Value = 210;
            app.zaxisEditFieldLabel = uilabel(app.UIFigure);
            app.zaxisEditFieldLabel.Text = 'z-axis';
            app.zaxisEditField = uieditfield(app.UIFigure, 'numeric');
            app.zaxisEditField.Value = 200;
            app.SliceButton = uibutton(app.UIFigure, 'push');
            app.SliceButton.ButtonPushedFcn = createCallbackFcn(app, @SliceButtonPushed, true);
            app.SliceButton.Text = 'Slice';
            app.PerimetersEditFieldLabel = uilabel(app.UIFigure);
            app.PerimetersEditFieldLabel.Text = 'Perimeters';
            app.PerimetersEditField = uieditfield(app.UIFigure, 'numeric');
            app.PerimetersEditField.Value = 8;
            app.mmLabel = uilabel(app.UIFigure);
            app.mmLabel.Text = 'mm';
            app.mmLabel_2 = uilabel(app.UIFigure);
            app.mmLabel_2.Text = 'mm';
            app.TraceOverlapEditFieldLabel = uilabel(app.UIFigure);
            app.TraceOverlapEditFieldLabel.Text = 'Trace Overlap';
            app.TraceOverlapEditField = uieditfield(app.UIFigure, 'numeric');
            app.TraceOverlapEditField.Value = 0.5;
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '%';
            app.MinimumTraceLengthEditFieldLabel = uilabel(app.UIFigure);
            app.MinimumTraceLengthEditFieldLabel.Text = 'Minimum Trace Length';
            app.MinimumTraceLengthEditField = uieditfield(app.UIFigure, 'numeric');
            app.MinimumTraceLengthEditField.Value = 5;
            app.mmLabel_3 = uilabel(app.UIFigure);
            app.mmLabel_3.Text = 'mm';
            app.TracePatternDropDownLabel = uilabel(app.UIFigure);
            app.TracePatternDropDownLabel.Text = 'Trace Pattern';
            app.TracePatternDropDown = uidropdown(app.UIFigure);
            app.TracePatternDropDown.Items = {'Concentric'};
            app.TracePatternDropDown.Value = 'Concentric';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = slicer_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end